classdef AMAP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RecoveredData                 matlab.ui.control.CheckBox
        MovementTypeLabel_2           matlab.ui.control.Label
        LoadDataLabel                 matlab.ui.control.Label
        CurrentFileDropDown           matlab.ui.control.DropDown
        CurrentFileDropDownLabel      matlab.ui.control.Label
        MovementDropDown              matlab.ui.control.DropDown
        MovementDropDownLabel         matlab.ui.control.Label
        MetricModificationsPanel      matlab.ui.container.Panel
        MetricSearchWindowLabel       matlab.ui.control.Label
        MetricSearchWindow            matlab.ui.control.Spinner
        REIUClickButton               matlab.ui.control.Button
        PVIUClickButton               matlab.ui.control.Button
        RSIUClickButton               matlab.ui.control.Button
        PVISpinnerLabel               matlab.ui.control.Label
        PVIndexSpinner                matlab.ui.control.Spinner
        StartIndexSpinnerLabel        matlab.ui.control.Label
        EndIndexSpinnerLabel          matlab.ui.control.Label
        RAStartIndexSpinner           matlab.ui.control.Spinner
        RAEndIndexSpinner             matlab.ui.control.Spinner
        MovementClassificationPanel   matlab.ui.container.Panel
        BadClassificationButton       matlab.ui.control.StateButton
        GoodClassificationButton      matlab.ui.control.StateButton
        TypeSelectorButtonGroup       matlab.ui.container.ButtonGroup
        GazeSelectionButton           matlab.ui.control.RadioButton
        RefractionSelectionButton     matlab.ui.control.RadioButton
        AccelerationSelectionsPanel   matlab.ui.container.Panel
        RefractionAccelerationPanel   matlab.ui.container.Panel
        AverageAccelerationCheckBox   matlab.ui.control.CheckBox
        RightAccelerationCheckBox     matlab.ui.control.CheckBox
        LeftAccelerationCheckBox      matlab.ui.control.CheckBox
        VelocitySelectionsPanel       matlab.ui.container.Panel
        GazeVelocityPanel             matlab.ui.container.Panel
        CombinedGazePVICheckBox       matlab.ui.control.CheckBox
        RightGazePVICheckBox          matlab.ui.control.CheckBox
        LeftGazePVICheckBox           matlab.ui.control.CheckBox
        CombinedGazeVelocityCheckBox  matlab.ui.control.CheckBox
        RightGazeVelocityCheckBox     matlab.ui.control.CheckBox
        LeftGazeVelocityCheckBox      matlab.ui.control.CheckBox
        RefractionVelocityPanel       matlab.ui.container.Panel
        AveragePVICheckBox            matlab.ui.control.CheckBox
        RightPVICheckBox              matlab.ui.control.CheckBox
        LeftPVICheckBox               matlab.ui.control.CheckBox
        AverageVelocityCheckBox       matlab.ui.control.CheckBox
        RightVelocityCheckBox         matlab.ui.control.CheckBox
        LeftVelocityCheckBox          matlab.ui.control.CheckBox
        PositionSelectionsPanel       matlab.ui.container.Panel
        GazePanel                     matlab.ui.container.Panel
        CombinedGazeREICheckBox       matlab.ui.control.CheckBox
        RightGazeREICheckBox          matlab.ui.control.CheckBox
        LeftGazeREICheckBox           matlab.ui.control.CheckBox
        CombinedGazeRSICheckBox       matlab.ui.control.CheckBox
        RightGazeRSICheckBox          matlab.ui.control.CheckBox
        LeftGazeRSICheckBox           matlab.ui.control.CheckBox
        CombinedGazeCheckBox          matlab.ui.control.CheckBox
        RightGazeCheckBox             matlab.ui.control.CheckBox
        LeftGazeCheckBox              matlab.ui.control.CheckBox
        RefractionPanel               matlab.ui.container.Panel
        AverageREICheckBox            matlab.ui.control.CheckBox
        AverageRSICheckBox            matlab.ui.control.CheckBox
        RightREICheckBox              matlab.ui.control.CheckBox
        LeftREICheckBox               matlab.ui.control.CheckBox
        RightRSICheckBox              matlab.ui.control.CheckBox
        LeftRSICheckBox               matlab.ui.control.CheckBox
        AverageRefractionCheckBox     matlab.ui.control.CheckBox
        RightRefractionCheckBox       matlab.ui.control.CheckBox
        LeftRefractionCheckBox        matlab.ui.control.CheckBox
        ExportButton                  matlab.ui.control.Button
        EyeSelectorButtonGroup        matlab.ui.container.ButtonGroup
        BinocularEyeSelectionButton   matlab.ui.control.RadioButton
        LeftEyeSelectionButton        matlab.ui.control.RadioButton
        RightEyeSelectionButton       matlab.ui.control.RadioButton
        EnsembleButton                matlab.ui.control.Button
        BatchPreprocessorButton       matlab.ui.control.Button
        VnelLogo                      matlab.ui.control.Image
        MovementSelectorRight         matlab.ui.control.Button
        MovementSelectorLeft          matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        NJIT_Logo                     matlab.ui.control.Image
        FilePreprocessorButton        matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        LoadDataButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Defined Variables
        timeVector;
        EyeSelectionDictionary;

        % Initialized Variables
        AverageAccelerationPlot
        AveragePeakVelocity
        AveragePVIPlot
        AverageRefraction
        AverageRefractionPlot
        AverageREIPlot
        AverageRSIPlot
        AverageVelocityPlot
        CombinedGazePlot
        CombinedGazePVIPlot
        CombinedGazeREIPlot
        CombinedGazeRSIPlot
        CombinedVelocityPlot
        DataPlots
        DictionaryKeyNameList
        DirectoryFileNames
        evalCheckBoxes
        EyeSelection
        FieldNames
        filename
        filepath
        GazeSelected
        graphProtection
        LeftAccelerationPlot
        LoadDataBoolean = false;
        LeftGazePlot
        LeftGazePVIPlot
        LeftGazeREIPlot
        LeftGazeRSIPlot
        LeftGazeVelocityPlot
        LeftPVIPlot
        LeftRefractionPlot
        LeftREIPlot
        LeftRSIPlot
        LeftVelocityPlot
        MetricMatrix
        MetricMatrixFilePath
        MetricMatrixName
        MetricSearchWindowSize
        MetricTableRowNames
        MetricTableColNames
        MovementClassification
        MovementCount
        MovementDictionary
        MovementMatrix
        MovementMetricDictionary
        MovementSelectedIndex
        RightAccelerationPlot
        RightGazePlot
        RightGazePVIPlot
        RightGazeREIPlot
        RightGazeRSIPlot
        RightGazeVelocityPlot
        RightPVIPlot
        RightREIPlot
        RightRefractionPlot
        RightRSIPlot
        RightVelocityPlot
        subject_id
        subject_file_name
        TypeSelection 
    end
    
    methods (Access = private)
        % Assess Selected CheckBox Handles for replotting 
        % via ValueChanged function(s)
        % Internal Method not accessible to User
        function replot(app,targetCheckBox,eyeSelectionIndex,replotAxesBoolean)
            % Case 1:
            % ~empty(targetCheckBox) (targetCheckBox is declared)
            % Assesses targetCheckBox handle 
            % Selected Checkbox Value == 1
            % eyeSelectionIndex (1 - Left, 2 - Right, 3 - Binocular)
            % invoked ""ValueChanged function to refresh plot
            if ~isempty(targetCheckBox)
                if(app.(targetCheckBox).Value == 1)
                        app.(strcat(targetCheckBox,'ValueChanged'))
                end
            % Case 2:
            % Assesses all CheckBox handles
            % Identify Selected Checkbox Value(s) == 1
            % eyeSelectionIndex (1 - Left, 2 - Right, 3 - Binocular)
            % invoked ""ValueChanged function to refresh plot
            else
                % Clear Figure
                cla(app.UIAxes);
                app.FieldNames = fieldnames(app);
                app.evalCheckBoxes = app.FieldNames(contains(app.FieldNames,'CheckBox','IgnoreCase',true));
                for index = 1:numel(app.evalCheckBoxes)
                    if(app.(app.evalCheckBoxes{index}).Value == 1)
                        app.(strcat(app.evalCheckBoxes{index},'ValueChanged'))
                    end
                end
                
                % Plot Horizontal Zero Line
                yline(app.UIAxes,0,'k');
            end

            % Generate Temp Matrix of Metrics
            metric_table_data = app.getMatrixData;

            % Case 1:
            % Data within movement has been modified
            % Update UITable with new data from EyeSelection Index
            if ~isempty(eyeSelectionIndex)
                logic_array = app.UITable.Data(eyeSelectionIndex,:) ~= metric_table_data(eyeSelectionIndex,:);
                app.UITable.Data(eyeSelectionIndex,logic_array) = metric_table_data(eyeSelectionIndex,logic_array);
            % Case 2:
            % New Eye Movement Loaded
            % Update UITable with Local Table
            else 
                app.UITable.Data(:,:) = metric_table_data;
            end

            % Adjust Axes for New Data
            if(replotAxesBoolean)
                app.replot_axes([],[]);
            end
        end
        
        % Dynamically calculates Y-Axis boundaries for plotted data
        function replot_axes(app,max_velocity,max_refraction)
            metricOffset = 0;
            
            % Clear YLim
            yyaxis(app.UIAxes,'left')
            set(app.UIAxes,'YTick',[])
            yyaxis(app.UIAxes,'right')
            set(app.UIAxes,'YTick',[])

            % Determine vergence type of plotted movement:
            % velocity < 0 (Divergence) || position < 0 (Divergence)
            % Conflicts default in Convergent Movement Types
            try
                [y_pos_lower_bound, y_pos_upper_bound, axis_ratios]  = app.defineAxisLimits(max_velocity,max_refraction);
                app.setGraphTicks(y_pos_lower_bound, y_pos_upper_bound, axis_ratios);         
            catch
                % Evaluate if first movement requires intervention
                % Else manual intervention on selected type and eye
                visibleMovementsBool = false; 
                for index = 1:numel(app.evalCheckBoxes)
                    if(app.(app.evalCheckBoxes{index}).Value == 1)
                        visibleMovementsBool = true;
                    end
                end
                if(~visibleMovementsBool)
                    app.EyeSelectorButtonGroup.SelectedObject = app.EyeSelectorButtonGroup.Children(1);
                    app.EyeSelectorButtonGroupSelectionChanged;
                    if(app.GazeSelected)
                        metricOffset = 6;
                        % Set Booleans
                        app.CombinedGazeCheckBox.Value = true;
                        app.CombinedGazeRSICheckBox.Value = true;
                        app.CombinedGazeREICheckBox.Value = true;
                        app.CombinedGazePVICheckBox.Value = true;
                        app.CombinedGazeVelocityCheckBox.Value = true;
    
                        % Plot
                        app.CombinedGazePVICheckBoxValueChanged;
                        app.CombinedGazeCheckBoxValueChanged;
                        app.CombinedGazeRSICheckBoxValueChanged;
                        app.CombinedGazeREICheckBoxValueChanged;
                        app.CombinedGazeVelocityCheckBoxValueChanged;
                    else
                        % Set Booleans
                        app.AverageRefractionCheckBox.Value = true;
                        app.AverageRSICheckBox.Value = true;
                        app.AverageREICheckBox.Value = true;
                        app.AveragePVICheckBox.Value = true;
                        app.AverageVelocityCheckBox.Value = true;
    
                        % Plot
                        app.AveragePVICheckBoxValueChanged;
                        app.AverageRefractionCheckBoxValueChanged;
                        app.AverageRSICheckBoxValueChanged;
                        app.AverageREICheckBoxValueChanged;
                        app.AverageVelocityCheckBoxValueChanged;
                    end
                end

                app.userMetricIntervention;

                % Reattempt Tick Setting
                try
                    [y_pos_lower_bound, y_pos_upper_bound, axis_ratios]  = app.defineAxisLimits(max_velocity,max_refraction);
                    app.setGraphTicks(y_pos_lower_bound, y_pos_upper_bound, axis_ratios); 
                % Else Manual Set Defaults
                catch
                    max_velocity = 5*sign(app.MetricMatrix(app.EyeSelection,1+metricOffset));
                    max_refraction = 1.5*sign(app.MetricMatrix(app.EyeSelection,2+metricOffset));
                    [y_pos_lower_bound, y_pos_upper_bound, axis_ratios]  = app.defineAxisLimits(max_velocity,max_refraction);
                    app.setGraphTicks(y_pos_lower_bound, y_pos_upper_bound, axis_ratios); 
                end
            end
        end
        
        % Internal Function that takes the absolute differe in position of REI - RSI
        function [recalculated_response_amplitude] = recalculate_response_amplitude(app)

            % Cast index values from seconds (.02 increments) to integers
            % 1/50 Samples/Second * Seconds -> Samples 
            starting_index = uint8((app.RAStartIndexSpinner.Value)*50);
            ending_index = uint8(((app.RAEndIndexSpinner.Value)*50));

            if(starting_index == 0)
                starting_index = 1;
            end

            if(starting_index <= ending_index)
                if(app.GazeSelected)
                    sub_pos(:,1)=app.MovementMatrix(starting_index:ending_index,4);
                    sub_pos(:,2)=app.MovementMatrix(starting_index:ending_index,11);
                    sub_pos(:,3)=sub_pos(:,1)+sub_pos(:,2);
                else
                    sub_pos(:,1)=app.MovementMatrix(starting_index:ending_index,1);
                    sub_pos(:,2)=app.MovementMatrix(starting_index:ending_index,8);
                    sub_pos(:,3)=(sub_pos(:,1)+sub_pos(:,2))/2;
                end
    
                % Recalulcate Response Amplitude based on difference
                % REI - RSI in position trace
                recalculated_response_amplitude = sub_pos(end,app.EyeSelection) - sub_pos(1,app.EyeSelection);
            else
                recalculated_response_amplitude = NaN;
            end
        end
        
        % Save current Metrics to current Movement Dictionary based on
        % current filename dropbox selection
        function updateMetricsDictionary(app)
            app.MovementMetricDictionary.Value(app.MovementDropDown.Value) = app.MetricMatrix;
        end
        
        % Create dictionary and key with selected file directory from load
        % data
        function createDirectory(app, filePath, fileName)
            % Load Directory
            currentDirectoryStructure = dir(fullfile(filePath));

            % Converts Struct to Cell Type
            directoryStructCell = struct2cell(currentDirectoryStructure);     

            % Checks isDir Boolean
            completeFileNames = directoryStructCell(1,cell2mat(directoryStructCell(5,:))~=1);

            % Removes Current File Name from Availability List
            temp_indexing = false(1,size(completeFileNames,2));
            for cells = 1:size(completeFileNames,2)
                temp_indexing(cells) = ~strcmp(string(completeFileNames{1,cells}),string(fileName));
            end

            % First File Name at Top
            app.DirectoryFileNames = {completeFileNames{~temp_indexing},completeFileNames{temp_indexing}};

            % Populates File Selection Dropdown
            app.CurrentFileDropDown.Items = app.DirectoryFileNames;
        end
        
        % Load metrics from directory determined by utilizing
        % regular expression to match filename of selected vergence file with metrics
        function metricsMatrixLoader(app, fileName, filePath, movementCount)
            
            try 
                processed_metrics_table_path = strrep(filePath,"Vergence","Processed_Metrics");
                processed_metrics_table_path = insertAfter(processed_metrics_table_path,strlength(processed_metrics_table_path)-1,"_Processed");
                processed_metric_table_partial_filename = convertStringsToChars(fileName(1:end-13)+"*");
                processed_metric_table_full_filename = dir(fullfile(processed_metrics_table_path,processed_metric_table_partial_filename));
                processed_metric_path_name = processed_metric_table_full_filename.name;               
                metric_data = horzcat(load(fullfile(processed_metrics_table_path,processed_metric_path_name)).current_data);
                app.MetricMatrixFilePath = fullfile(processed_metrics_table_path,processed_metric_path_name);
                app.MetricMatrixName = processed_metric_path_name;

                % Preallocate Space
                metricKeys = strings(1,size(metric_data,3));
                metricValues = cell(1,size(metric_data,3));
    
                % Create Key-Value Pairs for Metrics
                for metrics_matrix_number = 1:size(metric_data,3)
                    metricKeys(metrics_matrix_number) = "Movement "+num2str(metrics_matrix_number);
                    metricValues{metrics_matrix_number} = metric_data(:,:,metrics_matrix_number);
                end

            catch
                % PreAllocate NaN
                app.MovementClassification = NaN(3,1,movementCount);
    
                % Metrics Loader - Requires File Name and File Path
                metrics_table_path = strrep(filePath,"Vergence","Metrics");
                metric_table_partial_filename = convertStringsToChars(fileName(1:end-7)+"*");
                metric_table_full_filename = dir(fullfile(metrics_table_path,metric_table_partial_filename));
                metric_path_name = metric_table_full_filename.name;
                metric_data = horzcat(load(fullfile(metrics_table_path,metric_path_name)).current_data,app.MovementClassification);
                app.MetricMatrixFilePath = fullfile(metrics_table_path,metric_path_name);
                app.MetricMatrixName = metric_path_name;

                % Preallocate Space
                metricKeys = strings(1,size(metric_data,3));
                metricValues = cell(1,size(metric_data,3));
    
                % Create Key-Value Pairs for Metrics
                for metrics_matrix_number = 1:size(metric_data,3)
                    metricKeys(metrics_matrix_number) = "Movement "+num2str(metrics_matrix_number);
                    metricValues{metrics_matrix_number} = horzcat(metric_data(:,:,metrics_matrix_number),app.MovementClassification(:,:,metrics_matrix_number));
                end
            end
            
            % Generate Metrics Map with Same Key as Movements
            metric_matrix_dictionary = containers.Map(metricKeys,metricValues);
            app.MovementMetricDictionary.Value = metric_matrix_dictionary;

            % Load First Movement Metrics Matrix
            % Evaluate if Only First Movement Exists
            if(size(metric_matrix_dictionary(metricKeys(1)),1)==1)
                single_cell = metric_matrix_dictionary(metricKeys(1));
                app.MetricMatrix = single_cell{1};
            % Otherwise normal matrix loading
            else
                app.MetricMatrix = metric_matrix_dictionary(metricKeys(1));
            end

            PeakVelocity = metric_data(:,1,1);
            ResponseAmplitude = metric_data(:,2,1);
            FinalAmplitude = metric_data(:,3,1);

            % Adjust to 0 for temporal indices
            RSI = metric_data(:,4,1);
            PVI = metric_data(:,5,1);
            REI = metric_data(:,6,1);
            GV = metric_data(:,7,1);
            GRA = metric_data(:,8,1);
            GFA = metric_data(:,9,1);
            GRSI = metric_data(:,10,1);
            GVI = metric_data(:,11,1);
            GREI = metric_data(:,12,1);
            Classification = metric_data(:,13,1);

            
            metric_table_data = [PeakVelocity,ResponseAmplitude,FinalAmplitude,RSI,PVI,REI,GV,GRA,GFA,GRSI,GVI,GREI,Classification];           
            app.UITable.Data = metric_table_data;

            app.AveragePeakVelocity = metric_data(3,1);
            app.AverageRefraction = metric_data(3,3);
            
        end
        
        % Map metrics to movement data
        function movementMatrixLoader(app, cell_data, movementCount)
            % Preallocate Space
            app.DictionaryKeyNameList = strings(1,movementCount);
            valueSet = cell(1,movementCount);

            % Create Key-Value Pairs for Analysis
            for movement_number = 1:movementCount
                app.DictionaryKeyNameList(movement_number) = "Movement "+num2str(movement_number);
                valueSet{movement_number} = cell_data(:,:,movement_number);
            end
            
            % Generate Map
            movement_matrix_dictionary = containers.Map(app.DictionaryKeyNameList,valueSet);
            app.MovementDictionary.Value = movement_matrix_dictionary;

            % Populate Drop Down
            app.MovementDropDown.Items = app.DictionaryKeyNameList;

            % Set Dropdown First Value
            app.MovementDropDown.Value = app.DictionaryKeyNameList(1);

            % Set Graph Defaults
            % Safe Guard Single Movement Data Sets
            if(size(movement_matrix_dictionary(app.DictionaryKeyNameList(1)),1)==1)
                single_cell = movement_matrix_dictionary(app.DictionaryKeyNameList(1));
                app.MovementMatrix = single_cell{1};
            % Else normally load matrix
            else
                app.MovementMatrix = movement_matrix_dictionary(app.DictionaryKeyNameList(1));
            end
        end
        
        % Validate data is loaded utilizing state boolean
        function [LoadDataBoolean] = DataLoadedValidation(app)
            if(~app.LoadDataBoolean)
                app.LoadDataButtonPushed();
            end
            LoadDataBoolean = app.LoadDataBoolean;
        end
        
        % Unchecks all graphical options in AMAP GUI
        function clearGraphWindow(app)
            app.FieldNames = fieldnames(app);
            app.evalCheckBoxes = app.FieldNames(contains(app.FieldNames,'CheckBox','IgnoreCase',true));
            for index = 1:numel(app.evalCheckBoxes)
                if(app.(app.evalCheckBoxes{index}).Value == 1)
                    app.(app.evalCheckBoxes{index}).Value = 0;
                    app.(strcat(app.evalCheckBoxes{index},'ValueChanged'))
                end
            end
        end
        
        % Returns metrics with classification 1 (Good)
        function goodData = getGoodClassifications(app)
            % Return Movement Indexing
            sortedKeys = app.sortMovements;

            % Raw Movement Data
            % Check for Single Cell (Movement) Response
            raw_cell_data = values(app.MovementDictionary.Value);
            if(iscell(raw_cell_data{1}))
                raw_cell_data = raw_cell_data{1};
            end
            

            % Raw Movement Metric ( + Classification) Data
            metric_data = values(app.MovementMetricDictionary.Value);
            if(iscell(metric_data{1}))
                metric_data = metric_data{1};
            end

            % List of Indices to Remove
            bad_classification_indices = [];

            % Initialize Array
            goodData = cell(1,size(sortedKeys,1)); 
            
            % Assess Classification Type
            for classificationMovementIndex = 1:size(metric_data,2)
                % If bad classification then delete from cell array
                if (metric_data{classificationMovementIndex}(end,end-1) == 0)
                    bad_classification_indices = [bad_classification_indices;classificationMovementIndex];
                end
            end
            % Delete Bad Classifications
            sortedKeys(bad_classification_indices) = [];
            
            % Grabs Key from Index to Match with Data
            for (goodClassificationIndices = 1:1:size(sortedKeys,1))
                goodData{sortedKeys(goodClassificationIndices)} = raw_cell_data{goodClassificationIndices};
            end
        end
        
        % Validate all movements have been classified (classification being
        % size - 1 in column index with hidden movement number as last col
        function classificationStatus(app)
            % Check Classification Status
            completedClassification = cell2mat(values(app.MovementMetricDictionary.Value));
            completedClassification = completedClassification(:,floor(13:14:size(completedClassification,2)));
            if(all(any(~(isnan(completedClassification)))))
                % Message Dialog
                uialert(app.UIFigure,'Classification Complete','Classification Complete','Icon','success')
            end 
        end
        
        function updateSpinners(app)
            metricOffset = 0;
            if(app.GazeSelected)
                metricOffset = 6;
            end
            try
                app.RAStartIndexSpinner.Value = app.MetricMatrix(app.EyeSelection,4+metricOffset);
                app.PVIndexSpinner.Value = app.MetricMatrix(app.EyeSelection,5+metricOffset);
                app.RAEndIndexSpinner.Value = app.MetricMatrix(app.EyeSelection,6+metricOffset);
            catch
                % Generic Catch-All for Out-Of-Bound Values
                % RAStart Spinner
                if(app.MetricMatrix(app.EyeSelection,4+metricOffset) > 2.98)
                    app.MetricMatrix(app.EyeSelection,4+metricOffset) = 2.98;
                elseif(app.MetricMatrix(app.EyeSelection,4+metricOffset) < 0)
                    app.MetricMatrix(app.EyeSelection,4+metricOffset) = 0;
                end
                % PVI Spinner
                if(app.MetricMatrix(app.EyeSelection,5+metricOffset) > 2.98)
                    app.MetricMatrix(app.EyeSelection,5+metricOffset) = 2.98;
                elseif(app.MetricMatrix(app.EyeSelection,5+metricOffset) < 0)
                    app.MetricMatrix(app.EyeSelection,5+metricOffset) = 0;
                end
                % RAEnd Spinner
                if(app.MetricMatrix(app.EyeSelection,6+metricOffset) > 2.98)
                    app.MetricMatrix(app.EyeSelection,6+metricOffset) = 2.98;
                elseif(app.MetricMatrix(app.EyeSelection,6+metricOffset) <0)
                    app.MetricMatrix(app.EyeSelection,6+metricOffset) = 0;
                end
                app.RAStartIndexSpinner.Value = app.MetricMatrix(app.EyeSelection,4+metricOffset);
                app.PVIndexSpinner.Value = app.MetricMatrix(app.EyeSelection,5+metricOffset);
                app.RAEndIndexSpinner.Value = app.MetricMatrix(app.EyeSelection,6+metricOffset);

            end
        end
        
        function sortedMovementKeys = sortMovements(app)
            % Regex to get the sorted indices of dictionary
            temp_key_cells = regexp(keys(app.MovementMetricDictionary.Value),'(\d*)','match');
            sortedMovementKeys = zeros(size(temp_key_cells,2),1);

            % Convert Cell Arrays to Integers
            for iter=1:size(temp_key_cells,2)
                sortedMovementKeys(iter)=str2double(string(temp_key_cells{iter}));
            end
        end
        
        function currentDataMatrix = getMatrixData(app)
            % Load New Metric Data (Locally)
            peakVelocity = app.MetricMatrix(:,1);
            responseAmplitude = app.MetricMatrix(:,2);
            finalAmplitude = app.MetricMatrix(:,3);
            RSI = app.MetricMatrix(:,4);
            PVI = app.MetricMatrix(:,5);
            REI = app.MetricMatrix(:,6);
            GV = app.MetricMatrix(:,7);
            GRA = app.MetricMatrix(:,8);
            GFA = app.MetricMatrix(:,9);
            GRSI = app.MetricMatrix(:,10);
            GVI = app.MetricMatrix(:,11);
            GREI = app.MetricMatrix(:,12);
            Classification = app.MetricMatrix(:,13);

            % Update (Average) Movement Metrics
            % Provides updated boundaries for plotting
            app.AveragePeakVelocity = peakVelocity(3);
            app.AverageRefraction = finalAmplitude(3);

            % Create Array of Metrics
            currentDataMatrix = [peakVelocity,responseAmplitude,finalAmplitude,RSI,PVI,REI,GV,GRA,GFA,GRSI,GVI,GREI,Classification];   
        end
        
        function setGraphTicks(app, y_pos_lower_bound, y_pos_upper_bound, axis_ratios)
            metricOffset = 0;
            % Identify Type (Refraction or Gaze)
            if(app.GazeSelected)
                metricOffset = 6;
            end

            % Test Response Amplitude and Final Amplitude for Sign
            if(app.MetricMatrix(app.EyeSelection,2+metricOffset) < 0 && app.MetricMatrix(app.EyeSelection,1+metricOffset) < 0)
                    % Refraction
                    yyaxis(app.UIAxes,'right')
                    refraction_lower = linspace(0,y_pos_lower_bound,5);
                    refraction_upper = linspace(y_pos_upper_bound,0,10);
                    refraction_ticks = [refraction_upper,refraction_lower(2:end)];
                    ylim(app.UIAxes,[y_pos_upper_bound, y_pos_lower_bound]);
                    yticks(app.UIAxes,refraction_ticks);
                    
                    % Velocity
                    yyaxis(app.UIAxes,'left')
                    velocity_ticks = refraction_ticks*axis_ratios;
                    ylim(app.UIAxes,[y_pos_upper_bound*axis_ratios, y_pos_lower_bound*axis_ratios])
                    yticks(app.UIAxes,velocity_ticks)
    
            % Convergent Movements
            else
                try
                    % Refraction
                    yyaxis(app.UIAxes,'right')
                    refraction_lower = linspace(y_pos_lower_bound,0,5);
                    refraction_upper = linspace(0,y_pos_upper_bound,10);
                    refraction_ticks = [refraction_lower,refraction_upper(2:end)];
                    ylim(app.UIAxes,[y_pos_lower_bound,y_pos_upper_bound]);
                    yticks(app.UIAxes,refraction_ticks);
    
                    % Velocity
                    yyaxis(app.UIAxes,'left')
                    velocity_ticks = refraction_ticks*axis_ratios;
                    ylim(app.UIAxes,[y_pos_lower_bound*axis_ratios, y_pos_upper_bound*axis_ratios])
                    yticks(app.UIAxes,velocity_ticks)
                catch
                    % Refraction
                    yyaxis(app.UIAxes,'right')
                    refraction_lower = linspace(-5,0,5);
                    refraction_upper = linspace(0,10,10);
                    refraction_ticks = [refraction_lower,refraction_upper(2:end)];
                    ylim(app.UIAxes,[-5,10]);
                    yticks(app.UIAxes,refraction_ticks);
    
                    % Velocity
                    yyaxis(app.UIAxes,'left')
                    velocity_ticks = refraction_ticks*2.5;
                    ylim(app.UIAxes,[-5*2.5, 10*2.5])
                    yticks(app.UIAxes,velocity_ticks)
                end
            end  
        end
        
        function [y_pos_lower_bound, y_pos_upper_bound, axis_ratios]  = defineAxisLimits(app, max_velocity, max_refraction)

            % Default for Refraction
            metricOffset = 0;
            defaultLowerBoundBuffer = 4;
            defaultUpperBoundBuffer = 9;
            % Gaze (Vergence) has a 4:1 Ratio (Velocity/Position)
            % References Velocity to determine Vergence Type
            if (app.GazeSelected)
                metricOffset = 6;
                defaultLowerBoundBuffer = 8;
                defaultUpperBoundBuffer = 18;
            end
            % Missing Method Parameters will be overiden with default
            % Global Variable (Binocular) Refraction
            % NaN value passed treated as empty
            if(isempty(max_velocity) || isempty(max_refraction) || isnan(max_velocity) || isnan(max_refraction))
                if(~isnan(app.MetricMatrix(app.EyeSelection,2+metricOffset)))
                    bound_reference = app.MetricMatrix(app.EyeSelection,2+metricOffset);
                elseif(~isnan(app.MetricMatrix(3,2+metricOffset)))
                    bound_reference = app.MetricMatrix(3,2+metricOffset);
                elseif(~isnan(app.MetricMatrix(2,2+metricOffset)))
                    bound_reference = app.MetricMatrix(2,2+metricOffset);
                elseif(~isnan(app.MetricMatrix(1,2+metricOffset)))
                    bound_reference = app.MetricMatrix(1,2+metricOffset);
                end
                if (~isnan(app.MetricMatrix(app.EyeSelection,1+metricOffset)))
                    velocity_reference = app.MetricMatrix(app.EyeSelection,1+metricOffset);
                elseif(~isnan(app.MetricMatrix(3,1+metricOffset)))
                    velocity_reference = app.MetricMatrix(3,1+metricOffset);
                elseif(~isnan(app.MetricMatrix(2,1+metricOffset)))
                    velocity_reference = app.MetricMatrix(2,1+metricOffset);
                elseif(~isnan(app.MetricMatrix(1,1+metricOffset)))
                    velocity_reference = app.MetricMatrix(1,1+metricOffset);
                end
                    
                y_pos_lower_bound = -1*(1.25*floor(((bound_reference+(sign(bound_reference)*defaultLowerBoundBuffer))*.3125)/1.25));
                y_pos_upper_bound = 1.25*floor(((bound_reference+(sign(bound_reference)*defaultUpperBoundBuffer))*.3125)/1.25);
                axis_ratios = ceil(abs(velocity_reference/bound_reference));
                if(axis_ratios == 0 || isinf(axis_ratios) || axis_ratios > 15 || abs(velocity_reference) < abs(2.5*bound_reference) || abs(bound_reference) < 1)
                    axis_ratios = 2.5;
                elseif(axis_ratios < 3)
                    axis_ratios = axis_ratios * 2.5;
                end

            % Utilize given method parameters
            else
                y_pos_lower_bound = -1*(1.25*floor(((max_refraction+sign(max_refraction)*defaultLowerBoundBuffer)*.3125)/1.25));
                y_pos_upper_bound = 1.25*floor(((max_refraction+sign(max_refraction)*defaultUpperBoundBuffer)*.3125)/1.25);
                axis_ratios = ceil(abs(max_velocity/max_refraction));
                if(axis_ratios == 0 || isinf(axis_ratios) || axis_ratios > 10 || abs(max_velocity) < abs(2.5*max_refraction) || abs(max_refraction) < 1)
                    axis_ratios = 2.5;
                elseif(axis_ratios < 3)
                    axis_ratios = axis_ratios * 2.5;
                end
            end
        end
        
        function userMetricIntervention(app)
                % User Intervention
                app.graphProtection = true;
                uialert(app.UIFigure, "Select RSI from Graph", "Graphing Error Protection","Icon","warning","Modal",true)
                app.RSIUClickButtonPushed;
                % Clean Up Indices
                app.RAStartIndexSpinnerValueChanged;

                uialert(app.UIFigure, "Select PVI from Graph", "Graphing Error Protection","Icon","warning","Modal",true)
                app.PVIUClickButtonPushed;
                % Clean Up Indices
                app.PVIndexSpinnerValueChanged;

                uialert(app.UIFigure, "Select REI from Graph", "Graphing Error Protection","Icon","warning","Modal",true)
                app.REIUClickButtonPushed;
                % Clean Up Indices
                app.RAEndIndexSpinnerValueChanged;

                app.graphProtection = false;  
            
        end
        
        function indexValue = findIndexValue(app,indexType, selectedReference)
            % Set Defaults
            offsetValue = 0;
            searchWindowSize = app.MetricSearchWindowSize;

            % Evaluate for Gaze 
            % Refraction Vel Indices 2, 9
            % Gaze Vel Indices 5, 12
            if(app.GazeSelected)
                offsetValue = 3;
            end
            
            % Set Windows
            % Boundary Tests
            if(selectedReference*50 - searchWindowSize > 0)
                lowerWindowIndex = cast(selectedReference*50 - searchWindowSize,"uint8");
            else
                lowerWindowIndex = 1;
            end
            % Boundary Tests
            if(selectedReference*50 + searchWindowSize < size(app.MovementMatrix,1))
                upperWindowIndex = cast(selectedReference*50 + searchWindowSize,"uint8");
            else
                upperWindowIndex = size(app.MovementMatrix,1);
            end

            % Isolate Data to Analyze
            if(app.GazeSelected && app.EyeSelection == 3)
                currentEyeMovementData = app.MovementMatrix(lowerWindowIndex:upperWindowIndex,2+offsetValue)+app.MovementMatrix(lowerWindowIndex:upperWindowIndex,9+offsetValue);
            elseif(app.EyeSelection == 3)
                currentEyeMovementData = mean(app.MovementMatrix(lowerWindowIndex:upperWindowIndex,[2,9]),2);
            else
                eyeOffset = 7*(app.EyeSelection-1);
                currentEyeMovementData = app.MovementMatrix(lowerWindowIndex:upperWindowIndex,2+eyeOffset+offsetValue);
            end

            switch indexType
                case "intercept"
                    [~, foundIndex] = min(abs(currentEyeMovementData));

                case "peak"
                    [~, foundIndex] = max(abs(currentEyeMovementData));
            end 

            if(foundIndex-1 <= searchWindowSize)
                indexValue = selectedReference - (searchWindowSize - (foundIndex - 1))/50;
            elseif(foundIndex-1 == searchWindowSize + 1)
                indexValue = selectedReference;
            else
                indexValue = selectedReference + ((foundIndex - 1) - (searchWindowSize + 1))/50;
            end
            
            % Catch Boundaries
            if(indexValue < 0)
                indexValue = 0;
            elseif(indexValue > 3)
                indexValue = 3;
            end
        end
     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start Up Defaults
            movegui(app.UIFigure,"center")
            app.UIFigure.Name = "Accomodation Movement Analysis Program";
            app.UIAxes.Title.Interpreter = 'none';
            yyaxis(app.UIAxes,'right')
            ylabel(app.UIAxes,'Position')
            app.UITable.RowName = {'Left Eye', 'Right Eye', 'Average'};
            app.UITable.ColumnName = {'PV','RA','FA','RSI','PVI','REI','GV','GRA','GFA','GRSI','GVI','GREI','Classification'};
            app.UITable.ColumnWidth = 'auto';
            app.EyeSelection = 3;
            app.TypeSelection = 1;
            app.GazeSelected = false;
            app.timeVector = 0:.02:2.98;
            app.EyeSelectionDictionary = {'Left','Right','Binocular'};
            app.MetricSearchWindowSize = 4;
            % Plot Horizontal Zero Line
            yline(app.UIAxes,0,'k');
            app.graphProtection = false;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Clear Checkboxes (Previosuly Loaded Data)
            app.clearGraphWindow();

            % UI Call for Initial File and Path
            [app.filename, app.filepath] = uigetfile("MultiSelect","off",fullfile("C:/AMAP_Output/DATA/Vergence/"));

            if (~isequal(app.filename,0))

                % Catch Label for App
                parsedMovementType = strsplit(app.filepath,'\');
                parsedMovementType = strsplit(parsedMovementType{end-1},'_');
                app.LoadDataLabel.Text = parsedMovementType{end};

                [app.subject_id, app.subject_file_name] = FileNameSeparator.path_to_subject_id_and_filename(app.filepath, app.filename);

                % Breaks Struct Data into Matrix
                cell_data = cast(load(fullfile(app.filepath,app.filename)).current_data,"single");
    
                % Movement Dropdown
                app.movementMatrixLoader(cell_data,size(cell_data,3))

                % Set Title
                app.UIAxes.Title.String = app.filename;
                
                % Create Metric Matrix
                app.metricsMatrixLoader(app.filename, app.filepath,size(cell_data,3))

                app.LoadDataBoolean = true;

                % Refresh Plots
                app.updateSpinners;
                app.replot_axes([],[]);

                % Create File Selection Directory
                app.createDirectory(app.filepath, app.filename)
            else
                uialert(app.UIFigure,'Load Data Cancelled!','Process Cancellation','Icon','warning','Modal',true)
            end
        end

        % Value changed function: MovementDropDown
        function MovementDropDownValueChanged(app, event)
            if(app.DataLoadedValidation())
                if(size(app.MovementDictionary.Value(app.MovementDropDown.Value),1)==1)
                    single_movement_cell = app.MovementDictionary.Value(app.MovementDropDown.Value);
                    app.MovementMatrix = single_movement_cell{1};
                    app.MetricMatrix = app.MovementMetricDictionary.Value(app.MovementDropDown.Value);
                else
                    app.MovementMatrix = app.MovementDictionary.Value(app.MovementDropDown.Value);
                    app.MetricMatrix = app.MovementMetricDictionary.Value(app.MovementDropDown.Value);
                end
                
                app.updateSpinners;
                app.replot([],[],true);

                if(isnan(app.MetricMatrix(:,end-1)))
                    app.GoodClassificationButton.Value = false;
                    app.BadClassificationButton.Value = false;
                else
                    if(app.MetricMatrix(1,end-1) == 1)
                        app.GoodClassificationButton.Value = true;
                        app.BadClassificationButton.Value = false;
                    else
                        app.GoodClassificationButton.Value = false;
                        app.BadClassificationButton.Value = true;
                    end
                end
            end
        end

        % Value changed function: LeftRefractionCheckBox
        function LeftRefractionCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftRefractionPlot)
                if(app.LeftRefractionCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'right')
                    app.LeftRefractionPlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,1),'-b','LineWidth',1);
                    hold(app.UIAxes,'on')  
                end
            else
                app.LeftRefractionCheckBox.Value = false;
            end
        end

        % Value changed function: RightRefractionCheckBox
        function RightRefractionCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightRefractionPlot)
                if(app.RightRefractionCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'right')
                    app.RightRefractionPlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,8),'-r','LineWidth',1);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightRefractionCheckBox.Value = false;
            end
        end

        % Value changed function: AverageRefractionCheckBox
        function AverageRefractionCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.AverageRefractionPlot)
                if(app.AverageRefractionCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'right')
                    app.AverageRefractionPlot = plot(app.UIAxes,app.timeVector,(app.MovementMatrix(:,1) + app.MovementMatrix(:,8))/2,'-g','LineWidth',1,'DisplayName','Average Refraction (D)');
                    hold(app.UIAxes,'on')
                end
            else
                app.AverageRefractionCheckBox.Value = false;
            end
        end

        % Value changed function: LeftVelocityCheckBox
        function LeftVelocityCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftVelocityPlot)
                if(app.LeftVelocityCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'left')
                     app.LeftVelocityPlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,2).*50,'-.b','LineWidth',.5,'DisplayName','Left Velocity (D/s)');
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftVelocityCheckBox.Value = false;
            end
        end

        % Value changed function: RightVelocityCheckBox
        function RightVelocityCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightVelocityPlot)
                if(app.RightVelocityCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'left')
                    app.RightVelocityPlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,9).*50,'-.r','LineWidth',.5,'DisplayName','Right Velocity (D/s)');
                    hold(app.UIAxes,'on') 
                end
            else
                app.RightVelocityCheckBox.Value = false;
            end
        end

        % Value changed function: AverageVelocityCheckBox
        function AverageVelocityCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.AverageVelocityPlot)
                if(app.AverageVelocityCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'left')
                    app.AverageVelocityPlot = plot(app.UIAxes,app.timeVector,(app.MovementMatrix(:,2) + app.MovementMatrix(:,9)).*50/2,'-k','LineWidth',1,'DisplayName','Average Velocity (D/s)');
                    hold(app.UIAxes,'on')
                end
            else
                app.AverageVelocityCheckBox.Value = false;
            end
        end

        % Value changed function: LeftAccelerationCheckBox
        function LeftAccelerationCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftAccelerationPlot)
                if(app.LeftAccelerationCheckBox.Value == 1)
                    app.LeftAccelerationPlot = plot(app.UIAxes,app.timeVector,[0 ; diff((app.MovementMatrix(:,2)).*50)],':b','LineWidth',1,'DisplayName','Left LeftAccelerationPlot (D/s^2)');
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftAccelerationCheckBox.Value = false;
            end
        end

        % Value changed function: RightAccelerationCheckBox
        function RightAccelerationCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightAccelerationPlot)
                if(app.RightAccelerationCheckBox.Value == 1)
                    app.RightAccelerationPlot = plot(app.UIAxes,app.timeVector,[0 ; diff((app.MovementMatrix(:,9)).*50)],':r','LineWidth',1,'DisplayName','Right LeftAccelerationPlot (D/s^2)');
                    hold(app.UIAxes,'on') 
                end
            end
        end

        % Value changed function: AverageAccelerationCheckBox
        function AverageAccelerationCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.AverageAccelerationPlot)
                if(app.AverageAccelerationCheckBox.Value == 1)
                    app.AverageAccelerationPlot = plot(app.UIAxes,app.timeVector,[0; diff((app.MovementMatrix(:,2) + app.MovementMatrix(:,9)).*50/2)],':k','LineWidth',1.5,'DisplayName','Average Acceleration (D/s^2)');
                    hold(app.UIAxes,'on')
                end
            else
                app.AverageAccelerationCheckBox.Value = false;
            end
        end

        % Button pushed function: FilePreprocessorButton
        function FilePreprocessorButtonPushed(app, event)
            Preprocessor.preprocess_raw_data(false,[],[],app.UIFigure);
        end

        % Value changed function: LeftRSICheckBox
        function LeftRSICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftRSIPlot)
                if(app.LeftRSICheckBox.Value == 1)
                    app.LeftRSIPlot = xline(app.UIAxes,app.MetricMatrix(1,4),':b',{'RSI'},'LineWidth',1.5);
                    hold(app.UIAxes,'on')    
                end
            else
                app.LeftRSICheckBox.Value = false;
            end
        end

        % Value changed function: LeftREICheckBox
        function LeftREICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftREIPlot)
                if(app.LeftREICheckBox.Value == 1)
                    app.LeftREIPlot = xline(app.UIAxes,app.MetricMatrix(1,6),'--b',{'REI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')   
                end
            else
                app.LeftREICheckBox.Value = false;
            end
        end

        % Value changed function: RightRSICheckBox
        function RightRSICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightRSIPlot)
                if(app.RightRSICheckBox.Value == 1)
                    app.RightRSIPlot = xline(app.UIAxes,app.MetricMatrix(2,4),':r',{'RSI'},'LineWidth',1.5);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightRSICheckBox.Value = false;
            end
        end

        % Value changed function: RightREICheckBox
        function RightREICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightREIPlot)
                if(app.RightREICheckBox.Value == 1)
                    app.RightREIPlot = xline(app.UIAxes,app.MetricMatrix(2,6),'--r',{'REI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightREICheckBox.Value = false;
            end
        end

        % Value changed function: AverageRSICheckBox
        function AverageRSICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.AverageRSIPlot)
                if(app.AverageRSICheckBox.Value == 1)
                    app.AverageRSIPlot = xline(app.UIAxes,app.MetricMatrix(3,4),':k',{'RSI'},'LineWidth',1.5);
                    hold(app.UIAxes,'on')
                end
            else
                app.AverageRSICheckBox.Value = false;
            end
        end

        % Value changed function: AverageREICheckBox
        function AverageREICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.AverageREIPlot)
                if(app.AverageREICheckBox.Value == 1)
                    app.AverageREIPlot = xline(app.UIAxes,app.MetricMatrix(3,6),'--k',{'REI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.AverageREICheckBox.Value = false;
            end
        end

        % Value changed function: LeftPVICheckBox
        function LeftPVICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftPVIPlot)
                if(app.LeftPVICheckBox.Value == 1)
                    app.LeftPVIPlot = xline(app.UIAxes,app.MetricMatrix(1,5),'-.b',{'PVI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftPVICheckBox.Value = false;
            end
        end

        % Value changed function: RightPVICheckBox
        function RightPVICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightPVIPlot)
                if(app.RightPVICheckBox.Value == 1)
                    app.RightPVIPlot = xline(app.UIAxes,app.MetricMatrix(2,5),'-.r',{'PVI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightPVICheckBox.Value = false;
            end
        end

        % Value changed function: AveragePVICheckBox
        function AveragePVICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.AveragePVIPlot)
                if(app.AveragePVICheckBox.Value == 1)
                    app.AveragePVIPlot = xline(app.UIAxes,app.MetricMatrix(3,5),'-b',{'PVI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.AveragePVICheckBox.Value = false;
            end
        end

        % Value changed function: PVIndexSpinner
        function PVIndexSpinnerValueChanged(app, event)
            % Checks data is loaded and spinner value is 
            % within bounds (0 to 3 seconds)
            if(app.DataLoadedValidation() && (0 <= app.PVIndexSpinner.Value && app.PVIndexSpinner.Value < 3))
                % Provides index for Eye Selection
                eyeSelection = app.EyeSelectionDictionary{app.EyeSelection};

                pviSpinner = uint16((app.PVIndexSpinner.Value)*50);
                if (pviSpinner == 0)
                    pviSpinner = 1;
                end

                % Extracts all velocity traces and calculated velocity
                % traces (binocular) 
                velocity_traces=[app.MovementMatrix(:,2),app.MovementMatrix(:,9),mean(app.MovementMatrix(:,[2,9]),2),app.MovementMatrix(:,5),app.MovementMatrix(:,12),app.MovementMatrix(:,5) + app.MovementMatrix(:,12)];
                
                metricOffset = 0;
                velocityMetricOffset = 0;
                if(app.GazeSelected)
                    metricOffset = 6;
                    velocityMetricOffset = 3;

                    % Selects CheckBox Tags
                    if(app.EyeSelection == 3)
                        eyeSelection = 'Combined';
                    end
                    targetCheckBox = strcat(eyeSelection,"GazePVICheckBox");
                else

                    % Selects CheckBox Tags
                    if(app.EyeSelection == 3)
                        eyeSelection = 'Average';
                    end
                    targetCheckBox = strcat(eyeSelection,"PVICheckBox");
                end

                % Populates All Fields for Metric if Recovered Data Check
                % Box Selected
                if(app.RecoveredData.Value)
                    app.MetricMatrix([1,2,3],5+metricOffset) = app.PVIndexSpinner.Value;
                    app.MetricMatrix([1,2,3],1+metricOffset) = velocity_traces(pviSpinner,[1,2,3]+velocityMetricOffset)*50;
                else
                    app.MetricMatrix(app.EyeSelection,5+metricOffset) = app.PVIndexSpinner.Value;
                    app.MetricMatrix(app.EyeSelection,1+metricOffset) = velocity_traces(pviSpinner,app.EyeSelection+velocityMetricOffset)*50;
                end

                if(~app.graphProtection)
                    app.replot_axes(app.MetricMatrix(app.EyeSelection,1+metricOffset),app.MetricMatrix(app.EyeSelection,2+metricOffset))
                end

                app.updateMetricsDictionary();
                app.replot(targetCheckBox,app.EyeSelection,false);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Assess data is loaded and iterate through data to save as
            % "Processed" Data Metrics
            if(app.DataLoadedValidation())

                % Return Movement Indexing
                sortedKeys = app.sortMovements;

                temp_cell_array_of_values = values(app.MovementMetricDictionary.Value);
                cellarray_2_matrix = zeros(3,size(temp_cell_array_of_values{1},2),length(temp_cell_array_of_values));

                for i = 1:length(temp_cell_array_of_values)
                    cellarray_2_matrix(:,:,sortedKeys(i)) = temp_cell_array_of_values{i};
                end
                updatedMovementMetrics{1} = cellarray_2_matrix;
                SaveProgress.save_progress_to_mat(updatedMovementMetrics,app.subject_file_name,app.subject_id,"Processed_Metrics","Processed")

                % Message Dialog
                uialert(app.UIFigure,'Data Saved!','Save Complete','Icon','success')
            end
        end

        % Value changed function: RAStartIndexSpinner
        function RAStartIndexSpinnerValueChanged(app, event)
            % Assess data is loaded and spiner is within bounds
            if(app.DataLoadedValidation() && (0 <= app.RAStartIndexSpinner.Value && app.RAStartIndexSpinner.Value < 3))
                
                % Provides index for Eye Selection
                eyeSelection = app.EyeSelectionDictionary{app.EyeSelection};

                metricOffset = 0;
                % Identify if GazeSelected boolean is 1 (true)
                if(app.GazeSelected)
                    metricOffset = 6;

                    % Selects CheckBox Tags
                    if(app.EyeSelection == 3)
                        eyeSelection = 'Combined';
                    end
                    targetCheckBox = strcat(eyeSelection,"GazeRSICheckBox");
                else
                    % Selects CheckBox Tags
                    if(app.EyeSelection == 3)
                        eyeSelection = 'Average';
                    end
                    targetCheckBox = strcat(eyeSelection,"RSICheckBox");
                end

                % Populates All Fields for Metric if Recovered Data Check
                % Box Selected
                if(app.RecoveredData.Value)
                    app.MetricMatrix([1,2,3],4+metricOffset) = app.RAStartIndexSpinner.Value;
                    % Protect Current Index
                    currentSelection = app.EyeSelection;
                    % Iterate through each Eye Selection for RA Calculation
                    % Left Eye
                    app.EyeSelection = 1;
                    recalculated_RA = app.recalculate_response_amplitude();
                    app.MetricMatrix(1,2+metricOffset) = recalculated_RA;
                    % Right Eye
                    app.EyeSelection = 2;
                    recalculated_RA = app.recalculate_response_amplitude();
                    app.MetricMatrix(2,2+metricOffset) = recalculated_RA;
                    % Combined if not Gaze
                    if(~app.GazeSelected)
                        app.EyeSelection = 3;
                        recalculated_RA = app.recalculate_response_amplitude();
                        app.MetricMatrix(3,2+metricOffset) = recalculated_RA;
                    end
                    
                    % Restore previous Eye Selection
                    app.EyeSelection = currentSelection;
                else
                    app.MetricMatrix(app.EyeSelection,4+metricOffset) = app.RAStartIndexSpinner.Value;
                    recalculated_RA = app.recalculate_response_amplitude();
                    app.MetricMatrix(app.EyeSelection,2+metricOffset) = recalculated_RA;
                end

                % Adjust Boundaries
                if(~app.graphProtection)
                    app.replot_axes(app.MetricMatrix(app.EyeSelection,1+metricOffset),recalculated_RA)
                end

                app.updateMetricsDictionary();
                app.replot(targetCheckBox,app.EyeSelection,false);
            end
        end

        % Value changed function: RAEndIndexSpinner
        function RAEndIndexSpinnerValueChanged(app, event)
            if(app.DataLoadedValidation() && (0 <= app.RAEndIndexSpinner.Value && app.RAEndIndexSpinner.Value < 3))
                
                % Provides index for Eye Selection
                eyeSelection = app.EyeSelectionDictionary{app.EyeSelection};
                metricOffset = 0;

                % Identify if GazeSelected boolean is 1 (true)
                if(app.GazeSelected)
                    metricOffset = 6;

                    % Selects CheckBox Tags
                    if(app.EyeSelection == 3)
                        eyeSelection = 'Combined';
                    end
                    targetCheckBox = strcat(eyeSelection,"GazeREICheckBox");
                else
                    % Selects CheckBox Tags
                    if(app.EyeSelection == 3)
                        eyeSelection = 'Average';
                    end
                    targetCheckBox = strcat(eyeSelection,"REICheckBox");
                end

                % Populates All Fields for Metric if Recovered Data Check
                % Box Selected
                if(app.RecoveredData.Value)
                    app.MetricMatrix([1,2,3],6+metricOffset) = app.RAEndIndexSpinner.Value;
                    % Protect Current Index
                    currentSelection = app.EyeSelection;
                    % Iterate through each Eye Selection for RA Calculation
                    % Left Eye
                    app.EyeSelection = 1;
                    recalculated_RA = app.recalculate_response_amplitude();
                    app.MetricMatrix(1,2+metricOffset) = recalculated_RA;
                    % Right Eye
                    app.EyeSelection = 2;
                    recalculated_RA = app.recalculate_response_amplitude();
                    app.MetricMatrix(2,2+metricOffset) = recalculated_RA;
                    % Combined if not Gaze
                    if(~app.GazeSelected)
                        app.EyeSelection = 3;
                        recalculated_RA = app.recalculate_response_amplitude();
                        app.MetricMatrix(3,2+metricOffset) = recalculated_RA;
                    end
                        
                    % Restore previous Eye Selection
                    app.EyeSelection = currentSelection;
                else
                    app.MetricMatrix(app.EyeSelection,6+metricOffset) = app.RAEndIndexSpinner.Value;
                    recalculated_RA = app.recalculate_response_amplitude();
                    app.MetricMatrix(app.EyeSelection,2+metricOffset) = recalculated_RA;
                end

                % Adjust Boundaries
                if(~app.graphProtection)
                    app.replot_axes(app.MetricMatrix(app.EyeSelection,1+metricOffset),recalculated_RA)
                end
                
                app.updateMetricsDictionary(); 
                app.replot(targetCheckBox,app.EyeSelection,false);
            end
        end

        % Value changed function: GoodClassificationButton
        function GoodClassificationButtonValueChanged(app, event)
            % Assess if data is loaded else untoggle classification button
            if(app.DataLoadedValidation())
                % Compares state of classification button 
                % if 1 (true) sets classification to 1 (Good)
                if(app.GoodClassificationButton.Value)
                    app.BadClassificationButton.Value = false;
                    app.MetricMatrix(:,end-1) = 1;
                    app.updateMetricsDictionary();
                    app.classificationStatus();
                    % Visual Update for UITable 
                    app.UITable.Data(:,13) = 1;
                    app.MovementSelectorRightButtonPushed();
                else
                    app.GoodClassificationButton.Value = false;
                end
            end
        end

        % Value changed function: BadClassificationButton
        function BadClassificationButtonValueChanged(app, event)
            % Assess if data is loaded else untoggle classification button
            if(app.DataLoadedValidation())
                % Compares state of classification button 
                % if 1 (true) sets classification to 0 (Bad)
                if(app.BadClassificationButton.Value)
                    app.GoodClassificationButton.Value = false;
                    app.MetricMatrix(:,end-1) = 0;
                    app.updateMetricsDictionary();
                    app.classificationStatus();
                    % Visual Update for UITable 
                    app.UITable.Data(:,13) = 1;
                    app.MovementSelectorRightButtonPushed();
                else
                    app.BadClassificationButton.Value = false; 
                end
            end
        end

        % Button pushed function: MovementSelectorRight
        function MovementSelectorRightButtonPushed(app, event)
            % Assesses if data is loaded
            if(app.DataLoadedValidation())
                % Assesses if there are further movements to analyze
                % (compared to case with single file)
                if (~isempty(app.DictionaryKeyNameList))
                    temp_index = find(app.DictionaryKeyNameList == app.MovementDropDown.Value);

                    % Checks bounds, if >size() spills over to beginning of list else
                    % index increments one right (down list)
                    if(temp_index+1 > length(app.DictionaryKeyNameList))
                        app.MovementDropDown.Value = app.DictionaryKeyNameList(1);
                    else
                        app.MovementDropDown.Value = app.DictionaryKeyNameList(temp_index+1);
                    end
                    app.MovementDropDownValueChanged;
                end
            end
        end

        % Button pushed function: MovementSelectorLeft
        function MovementSelectorLeftButtonPushed(app, event)
            % Validate Data loaded
            if(app.DataLoadedValidation())
                % Assesses if there are further movements to analyze
                % (compared to case with single file)
                if (~isempty(app.DictionaryKeyNameList))
                    temp_index = find(app.DictionaryKeyNameList == app.MovementDropDown.Value);

                    % Checks bounds, if 0 spills over to end of list else
                    % index increments one left (up list)
                    if(temp_index-1 == 0)
                        app.MovementDropDown.Value = app.DictionaryKeyNameList(end);
                    else
                        app.MovementDropDown.Value = app.DictionaryKeyNameList(temp_index-1);
                    end
                    app.MovementDropDownValueChanged;
                end
            end
        end

        % Value changed function: CurrentFileDropDown
        function CurrentFileDropDownValueChanged(app, event)
            if(app.DataLoadedValidation())

                app.filename = app.CurrentFileDropDown.Value;
    
                [app.subject_id, app.subject_file_name] = FileNameSeparator.path_to_subject_id_and_filename(app.filepath, app.filename);
    
                % Breaks Struct Data into Matrix
                cell_data = cast(load(fullfile(app.filepath,app.filename)).current_data,"single");
    
                % Movement Dropdown
                app.movementMatrixLoader(cell_data,size(cell_data,3))
    
                % Set Title
                app.UIAxes.Title.String = app.filename;
                
                % Create Metric Matrix
                app.metricsMatrixLoader(app.filename, app.filepath,size(cell_data,3))
    
                % Refresh Plots
                app.clearGraphWindow
    
                % Create File Selection Directory
                app.createDirectory(app.filepath, app.filename)

                app.CurrentFileDropDown.Value = app.filename;
            end
        end

        % Button pushed function: BatchPreprocessorButton
        function BatchPreprocessorButtonPushed(app, event)
            BatchProcessor.batch_process(app.UIFigure);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if(app.DataLoadedValidation())
                % Hot Keys for Ease of Use
                switch event.Key
                    case 'a'
                        app.MovementSelectorLeftButtonPushed();
                    case 'd'
                        app.MovementSelectorRightButtonPushed();
                    case 'g'
                        app.GoodClassificationButton.Value = true;
                        app.GoodClassificationButtonValueChanged();
                    case 'b'
                        app.BadClassificationButton.Value = true;
                        app.BadClassificationButtonValueChanged();
                    case 'z'
                        app.EyeSelectorButtonGroup.SelectedObject = app.EyeSelectorButtonGroup.Children(2);
                        app.EyeSelectorButtonGroupSelectionChanged;

                        if(app.GazeSelected)
                            % Set Booleans
                            app.LeftGazeCheckBox.Value = true;
                            app.LeftGazeRSICheckBox.Value = true;
                            app.LeftGazeREICheckBox.Value = true;
                            app.LeftGazePVICheckBox.Value = true;
                            app.LeftGazeVelocityCheckBox.Value = true;
    
                            % Plot
                            app.LeftGazePVICheckBoxValueChanged;
                            app.LeftGazeCheckBoxValueChanged;
                            app.LeftGazeRSICheckBoxValueChanged;
                            app.LeftGazeREICheckBoxValueChanged;
                            app.LeftGazeVelocityCheckBoxValueChanged;
                        else
                            % Set Booleans
                            app.LeftRefractionCheckBox.Value = true;
                            app.LeftRSICheckBox.Value = true;
                            app.LeftREICheckBox.Value = true;
                            app.LeftPVICheckBox.Value = true;
                            app.LeftVelocityCheckBox.Value = true;
    
                            % Plot
                            app.LeftPVICheckBoxValueChanged;
                            app.LeftRefractionCheckBoxValueChanged;
                            app.LeftRSICheckBoxValueChanged;
                            app.LeftREICheckBoxValueChanged;
                            app.LeftVelocityCheckBoxValueChanged;
                        end
                    case 'x'
                        app.EyeSelectorButtonGroup.SelectedObject = app.EyeSelectorButtonGroup.Children(3);
                        app.EyeSelectorButtonGroupSelectionChanged;
                        if(app.GazeSelected)
                            % Set Booleans
                            app.RightGazeCheckBox.Value = true;
                            app.RightGazeRSICheckBox.Value = true;
                            app.RightGazeREICheckBox.Value = true;
                            app.RightGazePVICheckBox.Value = true;
                            app.RightGazeVelocityCheckBox.Value = true;
                            
                            % Plot
                            app.RightGazePVICheckBoxValueChanged;
                            app.RightGazeCheckBoxValueChanged;
                            app.RightGazeRSICheckBoxValueChanged;
                            app.RightGazeREICheckBoxValueChanged;
                            app.RightGazeVelocityCheckBoxValueChanged;
                        else
                            % Set Booleans
                            app.RightRefractionCheckBox.Value = true;
                            app.RightRSICheckBox.Value = true;
                            app.RightREICheckBox.Value = true;
                            app.RightPVICheckBox.Value = true;
                            app.RightVelocityCheckBox.Value = true;
                            
                            % Plot
                            app.RightPVICheckBoxValueChanged;
                            app.RightRefractionCheckBoxValueChanged;
                            app.RightRSICheckBoxValueChanged;
                            app.RightREICheckBoxValueChanged;
                            app.RightVelocityCheckBoxValueChanged;
                        end

                    case 'c'
                        app.EyeSelectorButtonGroup.SelectedObject = app.EyeSelectorButtonGroup.Children(1);
                        app.EyeSelectorButtonGroupSelectionChanged;
                        if(app.GazeSelected)
                            % Set Booleans
                            app.CombinedGazeCheckBox.Value = true;
                            app.CombinedGazeRSICheckBox.Value = true;
                            app.CombinedGazeREICheckBox.Value = true;
                            app.CombinedGazePVICheckBox.Value = true;
                            app.CombinedGazeVelocityCheckBox.Value = true;
    
                            % Plot
                            app.CombinedGazePVICheckBoxValueChanged;
                            app.CombinedGazeCheckBoxValueChanged;
                            app.CombinedGazeRSICheckBoxValueChanged;
                            app.CombinedGazeREICheckBoxValueChanged;
                            app.CombinedGazeVelocityCheckBoxValueChanged;
                        else
                            % Set Booleans
                            app.AverageRefractionCheckBox.Value = true;
                            app.AverageRSICheckBox.Value = true;
                            app.AverageREICheckBox.Value = true;
                            app.AveragePVICheckBox.Value = true;
                            app.AverageVelocityCheckBox.Value = true;
    
                            % Plot
                            app.AveragePVICheckBoxValueChanged;
                            app.AverageRefractionCheckBoxValueChanged;
                            app.AverageRSICheckBoxValueChanged;
                            app.AverageREICheckBoxValueChanged;
                            app.AverageVelocityCheckBoxValueChanged;
                        end

                    case 'f'
                        app.clearGraphWindow;

                    case 'q' % Quick Select RSI Index
                        app.RSIUClickButtonPushed;

                    case 'w' % Quick Select PVI Index
                        app.PVIUClickButtonPushed;

                    case 'e' % Quick Select REI Index
                        app.REIUClickButtonPushed;

                    case 'r' % Refraction Type
                        app.TypeSelectorButtonGroup.SelectedObject = app.TypeSelectorButtonGroup.Children(2);
                        app.TypeSelectorButtonGroupSelectionChanged;

                    case 's' % Quick Save
                        app.SaveButtonPushed;

                    case 't' % Gaze Type
                        app.TypeSelectorButtonGroup.SelectedObject = app.TypeSelectorButtonGroup.Children(1);
                        app.TypeSelectorButtonGroupSelectionChanged;
                    case 'm' % Recovered Data Checkbox Trigger
                        app.RecoveredData.Value = true;
                    case 'n' % Recovered Data Checkbox Untrigger
                        app.RecoveredData.Value = false;
                end
            end
        end

        % Button pushed function: EnsembleButton
        function EnsembleButtonPushed(app, event)
            % Generate Ensemble of Average of All Movements for Given File
            if(app.DataLoadedValidation())
                % Set All Plotting Options to False
                app.clearGraphWindow();

                % Select Ensemble Type
                [selectionIndex] = EnsembleUISelector.ensemble_selection();
                if(~isempty(selectionIndex))
                    [errorBar_ColorSelection,trace_ColorSelection,subject_Type,movement_type] = EnsembleColorSelector.color_selection(app.UIAxes.Title.String);
                    if(~isempty(errorBar_ColorSelection))
                        dataMovementCellArray = app.getGoodClassifications();
                        movement_id = app.UIAxes.Title.String;
                        switch selectionIndex
                            case 1 % Subject Ensemble
                                % Check Classification Status
                                classification_cell = values(app.MovementMetricDictionary.Value);
                                if(iscell(classification_cell{1}))
                                    completedClassification = cell2mat(classification_cell{1});
                                else
                                    completedClassification = cell2mat(values(app.MovementMetricDictionary.Value));
                                end
                                % 14 Denotes the Total Number of Metrics
                                % Captured per Movement
                                completedClassification = completedClassification(:,[floor(13:14:size(completedClassification,2))]);
                                if(all(any(~(isnan(completedClassification)))))
                                    % Message Dialog
                                    MovementEnsemble.individualMovementEnsemble(dataMovementCellArray,movement_id,trace_ColorSelection,errorBar_ColorSelection,subject_Type,movement_type)
                                else
                                    uialert(app.UIFigure,'Classification NOT Complete for Ensemble','Incomplete Classification')
                                end
                            case 2 % Combined Ensemble
                                GroupLevelEnsemble.groupLevelEnsemble(movement_type);
                        end
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Provide warning for UI closure
            selection = uiconfirm(app.UIFigure,'Close AMAP?','Confirm Exit','Icon','warning','Options',{'Yes','Cancel'});
                switch selection
                    case 'Yes'
                        delete(app)
                    case 'Cancel'
                end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            if(app.DataLoadedValidation())
                % Scroll Down is Positive Count
                % Scroll Up is Negative Count
                switch hittest(app.UIFigure).Tag
                    case "ScrollablePVI"
                        if(app.DataLoadedValidation() && (0<= app.PVIndexSpinner.Value && app.PVIndexSpinner.Value <= 3))
                            indexIncrement = app.PVIndexSpinner.Value + .02*event.VerticalScrollCount;
                            if(indexIncrement >=0 && indexIncrement <=3)
                                app.PVIndexSpinner.Value = indexIncrement;
                                app.PVIndexSpinnerValueChanged;
                            end   
                        end
                    case "ScrollableREI"
                        if(app.DataLoadedValidation() && (0 <= app.RAEndIndexSpinner.Value && app.RAEndIndexSpinner.Value <= 3))
                            indexIncrement = app.RAEndIndexSpinner.Value + .02*event.VerticalScrollCount;
                            if(indexIncrement >=0 && indexIncrement <=3)
                                app.RAEndIndexSpinner.Value = indexIncrement;
                                app.RAEndIndexSpinnerValueChanged;
                            end
                        end
                    case "ScrollableRSI"
                        if(app.DataLoadedValidation() && (0<= app.RAStartIndexSpinner.Value && app.RAStartIndexSpinner.Value <= 3))
                            indexIncrement = app.RAStartIndexSpinner.Value + .02*event.VerticalScrollCount;
                            if(indexIncrement >=0 && indexIncrement <=3)
                                app.RAStartIndexSpinner.Value = indexIncrement;
                                app.RAStartIndexSpinnerValueChanged;
                            end
                        end
                    case 'opaque'
  
                end
            end
        end

        % Selection changed function: EyeSelectorButtonGroup
        function EyeSelectorButtonGroupSelectionChanged(app, event)
            % Switch Tags based on Eye Movement Selector
            if(app.DataLoadedValidation())
                switch app.EyeSelectorButtonGroup.SelectedObject.Tag
                    case "LeftEyeMovementSelection"
                        app.EyeSelection = 1;
                    case "RightEyeMovementSelection"
                        app.EyeSelection = 2;
                    case "BinocularEyeMovementSelection"
                        app.EyeSelection = 3;
                end
                app.updateSpinners;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
           % Calls subroutine
            ExportData.export(false);
        end

        % Value changed function: LeftGazeCheckBox
        function LeftGazeCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftGazePlot)
                if(app.LeftGazeCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'right')
                    app.LeftGazePlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,4),'--b','LineWidth',1);
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftGazeCheckBox.Value = false;
            end
        end

        % Value changed function: RightGazeCheckBox
        function RightGazeCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightGazePlot)
                if(app.RightGazeCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'right')
                    app.RightGazePlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,11),'--r','LineWidth',1);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightGazeCheckBox.Value = false;
            end
        end

        % Value changed function: CombinedGazeCheckBox
        function CombinedGazeCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.CombinedGazePlot)
                if(app.CombinedGazeCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'right')
                    app.CombinedGazePlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,4)+app.MovementMatrix(:,11),'--g','LineWidth',1);
                    hold(app.UIAxes,'on')
                end
            else
                app.CombinedGazeCheckBox.Value = false;
            end
        end

        % Value changed function: LeftGazeVelocityCheckBox
        function LeftGazeVelocityCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftGazeVelocityPlot)
                if(app.LeftGazeVelocityCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'left')
                    app.LeftGazeVelocityPlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,5).*50,'-.b','LineWidth',.5,'DisplayName','Left Gaze Velocity (Deg/s)');
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftGazeVelocityCheckBox.Value = false;
            end
        end

        % Value changed function: RightGazeVelocityCheckBox
        function RightGazeVelocityCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightGazeVelocityPlot)
                if(app.RightGazeVelocityCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'left')
                    app.RightGazeVelocityPlot = plot(app.UIAxes,app.timeVector,app.MovementMatrix(:,12).*50,'-.r','LineWidth',.5,'DisplayName','Right Gaze Velocity (Deg/s)');
                    hold(app.UIAxes,'on')
                end
            else
                app.RightGazeVelocityCheckBox.Value = false;
            end
        end

        % Value changed function: CombinedGazeVelocityCheckBox
        function CombinedGazeVelocityCheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.CombinedVelocityPlot)
                if(app.CombinedGazeVelocityCheckBox.Value == 1)
                    yyaxis(app.UIAxes,'left')
                    app.CombinedVelocityPlot = plot(app.UIAxes,app.timeVector,(app.MovementMatrix(:,5) + app.MovementMatrix(:,12)).*50,'-k','LineWidth',1,'DisplayName','Combined Gaze Velocity (Deg/s)');
                    hold(app.UIAxes,'on')
                end
            else
                app.CombinedGazeVelocityCheckBox.Value = false;
            end
        end

        % Selection changed function: TypeSelectorButtonGroup
        function TypeSelectorButtonGroupSelectionChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                switch app.TypeSelectorButtonGroup.SelectedObject.Tag
                    case "GazeSelection"
                        app.TypeSelection = 2;
                        app.GazeSelected = true;
                    case "RefractionSelection"
                        app.TypeSelection = 1;
                        app.GazeSelected = false;
                end
                app.updateSpinners;
                app.replot([],[],true);
            end
        end

        % Value changed function: LeftGazePVICheckBox
        function LeftGazePVICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftGazePVIPlot)
                if(app.LeftGazePVICheckBox.Value == 1)
                    app.LeftGazePVIPlot = xline(app.UIAxes,app.MetricMatrix(1,11),'-.b',{'PVI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftGazePVICheckBox.Value = false;
            end
        end

        % Value changed function: RightGazePVICheckBox
        function RightGazePVICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightGazePVIPlot)
                if(app.RightGazePVICheckBox.Value == 1)
                    app.RightGazePVIPlot = xline(app.UIAxes,app.MetricMatrix(2,11),'-.r',{'PVI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightGazePVICheckBox.Value = false;
            end
        end

        % Value changed function: CombinedGazePVICheckBox
        function CombinedGazePVICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.CombinedGazePVIPlot)
                if(app.CombinedGazePVICheckBox.Value == 1)
                    app.CombinedGazePVIPlot = xline(app.UIAxes,app.MetricMatrix(3,11),'-b',{'PVI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.CombinedGazePVICheckBox.Value = false;
            end
        end

        % Value changed function: LeftGazeRSICheckBox
        function LeftGazeRSICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftGazeRSIPlot)
                if(app.LeftGazeRSICheckBox.Value == 1)
                    app.LeftGazeRSIPlot = xline(app.UIAxes,app.MetricMatrix(1,10),':b',{'RSI'},'LineWidth',1.5);
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftGazeRSICheckBox.Value = false;
            end
        end

        % Value changed function: RightGazeRSICheckBox
        function RightGazeRSICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightGazeRSIPlot)
                if(app.RightGazeRSICheckBox.Value == 1)
                    app.RightGazeRSIPlot = xline(app.UIAxes,app.MetricMatrix(2,10),':r',{'RSI'},'LineWidth',1.5);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightGazeRSICheckBox.Value = false;
            end
        end

        % Value changed function: CombinedGazeRSICheckBox
        function CombinedGazeRSICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.CombinedGazeRSIPlot)
                if(app.CombinedGazeRSICheckBox.Value == 1)
                    app.CombinedGazeRSIPlot = xline(app.UIAxes,app.MetricMatrix(3,10),':k',{'RSI'},'LineWidth',1.5);
                    hold(app.UIAxes,'on')
                end
            else
                app.CombinedGazeRSICheckBox.Value = false;
            end
            
        end

        % Value changed function: LeftGazeREICheckBox
        function LeftGazeREICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.LeftGazeREIPlot)
                if(app.LeftGazeREICheckBox.Value == 1)
                    app.LeftGazeREIPlot = xline(app.UIAxes,app.MetricMatrix(1,12),'--b',{'REI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.LeftGazeREICheckBox.Value = false;
            end
            
        end

        % Value changed function: RightGazeREICheckBox
        function RightGazeREICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.RightGazeREIPlot)
                if(app.RightGazeREICheckBox.Value == 1)
                    app.RightGazeREIPlot = xline(app.UIAxes,app.MetricMatrix(2,12),'--r',{'REI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.RightGazeREICheckBox.Value = false;
            end
            
        end

        % Value changed function: CombinedGazeREICheckBox
        function CombinedGazeREICheckBoxValueChanged(app, event)
            % Assesses if DataLoaded (boolean)
            % Default deletes handle of graph (for efficiency)
            % Evaluates if checkbox has been selected and plots if 1 (true)
            if(app.DataLoadedValidation())
                delete(app.CombinedGazeREIPlot)
                if(app.CombinedGazeREICheckBox.Value == 1)
                    app.CombinedGazeREIPlot = xline(app.UIAxes,app.MetricMatrix(3,12),'--k',{'REI'},'LineWidth',1.25);
                    hold(app.UIAxes,'on')
                end
            else
                app.CombinedGazeREICheckBox.Value = false;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.UITable.Data(event.Indices(1),event.Indices(2)) = event.NewData;
        end

        % Button pushed function: RSIUClickButton
        function RSIUClickButtonPushed(app, event)
            if(app.DataLoadedValidation())
                
                % Store UIFigure Visibility State
                handleVisbilityState = app.UIFigure.HandleVisibility;
                % Temp Turn On Visibility
                app.UIFigure.HandleVisibility = 'callback';
                % Set Figure for ginput
                set(0,'CurrentFigure',app.UIFigure)
                
                try
                    % User Selection
                    [indexSelection, ~] = ginput(1);
    
                    % Verify Input
                    if(~isempty(indexSelection))
                        % Value Correction
                        if(mod(indexSelection,.02)~=0)
                            indexSelection = indexSelection - mod(indexSelection,.02);
                        end

                        % Search Promiximity 
                        indexSelection = app.findIndexValue("intercept", indexSelection);

                        app.RAStartIndexSpinner.Value = indexSelection;
                        if(~app.graphProtection)
                            app.RAStartIndexSpinnerValueChanged();
                        end
                    else
                        uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                    end
                catch
                    uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                end
                % Return Visibility State
                app.UIFigure.HandleVisibility = handleVisbilityState;
            end
        end

        % Button pushed function: REIUClickButton
        function REIUClickButtonPushed(app, event)
            if(app.DataLoadedValidation())
                % Store UIFigure Visibility State
                handleVisbilityState = app.UIFigure.HandleVisibility;
                % Temp Turn On Visibility
                app.UIFigure.HandleVisibility = 'callback';
                % Set Figure for ginput
                set(0,'CurrentFigure',app.UIFigure)
                
                try
                    % User Selection
                    [indexSelection, ~] = ginput(1);
    
                    % Verify Input
                    if(~isempty(indexSelection))
                        % Value Correction
                        if(mod(indexSelection,.02)~=0)
                            indexSelection = indexSelection - mod(indexSelection,.02);
                        end

                        % Search Promiximity 
                        indexSelection = app.findIndexValue("intercept", indexSelection);

                        app.RAEndIndexSpinner.Value = indexSelection;
                        if(~app.graphProtection)
                            app.RAEndIndexSpinnerValueChanged();
                        end
                    else
                        uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                    end
                catch
                    uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                end
                % Return Visibility State
                app.UIFigure.HandleVisibility = handleVisbilityState;
            end
        end

        % Button pushed function: PVIUClickButton
        function PVIUClickButtonPushed(app, event)
            if(app.DataLoadedValidation())
                % Store UIFigure Visibility State
                handleVisbilityState = app.UIFigure.HandleVisibility;
                % Temp Turn On Visibility
                app.UIFigure.HandleVisibility = 'callback';
                % Set Figure for ginput
                set(0,'CurrentFigure',app.UIFigure)
                
                try
                    % User Selection
                    [indexSelection, ~] = ginput(1);
    
                    % Verify Input
                    if(~isempty(indexSelection))
                        % Value Correction
                        if(mod(indexSelection,.02)~=0)
                            indexSelection = indexSelection - mod(indexSelection,.02);
                        end

                        % Search Promiximity 
                        indexSelection = app.findIndexValue("peak", indexSelection);

                        app.PVIndexSpinner.Value = indexSelection;
                        if(~app.graphProtection)
                            app.PVIndexSpinnerValueChanged();
                        end
                    else
                        uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                    end
                catch
                    uialert(app.UIFigure,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                end
                % Return Visibility State
                app.UIFigure.HandleVisibility = handleVisbilityState;
            end
        end

        % Value changed function: MetricSearchWindow
        function MetricSearchWindowValueChanged(app, event)
            app.MetricSearchWindowSize = app.MetricSearchWindow.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'AMAP_Cover.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Accommodative Movement')
            xlabel(app.UIAxes, 'Time (Seconds)')
            ylabel(app.UIAxes, 'Velocity (Position/Seconds)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'graphingWindow';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PV'; 'RA'; 'FA'; 'RSI'; 'PVI'; 'REI'; 'GV'; 'GRA'; 'GFA'; 'GRSI'; 'GVI'; 'GREI'; 'Classification'};
            app.UITable.RowName = {'Left, Right,Average'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.FilePreprocessorButton = uibutton(app.UIFigure, 'push');
            app.FilePreprocessorButton.ButtonPushedFcn = createCallbackFcn(app, @FilePreprocessorButtonPushed, true);
            app.FilePreprocessorButton.Text = 'File Preprocessor';
            app.NJIT_Logo = uiimage(app.UIFigure);
            app.NJIT_Logo.ImageSource = 'NJIT transparent.png';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MovementSelectorLeft = uibutton(app.UIFigure, 'push');
            app.MovementSelectorLeft.ButtonPushedFcn = createCallbackFcn(app, @MovementSelectorLeftButtonPushed, true);
            app.MovementSelectorLeft.Icon = 'AMAP_GUI_Arrows_Left_Blank.png';
            app.MovementSelectorLeft.Text = '';
            app.MovementSelectorRight = uibutton(app.UIFigure, 'push');
            app.MovementSelectorRight.ButtonPushedFcn = createCallbackFcn(app, @MovementSelectorRightButtonPushed, true);
            app.MovementSelectorRight.Icon = 'AMAP_Arrow_Right_Blank.png';
            app.MovementSelectorRight.Text = '';
            app.VnelLogo = uiimage(app.UIFigure);
            app.VnelLogo.ImageSource = 'VNEL_Logo.svg';
            app.BatchPreprocessorButton = uibutton(app.UIFigure, 'push');
            app.BatchPreprocessorButton.ButtonPushedFcn = createCallbackFcn(app, @BatchPreprocessorButtonPushed, true);
            app.BatchPreprocessorButton.Text = 'Batch Preprocessor';
            app.EnsembleButton = uibutton(app.UIFigure, 'push');
            app.EnsembleButton.ButtonPushedFcn = createCallbackFcn(app, @EnsembleButtonPushed, true);
            app.EnsembleButton.Text = 'Ensemble';
            app.EyeSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.EyeSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeSelectorButtonGroupSelectionChanged, true);
            app.EyeSelectorButtonGroup.Title = 'Eye Selector';
            app.EyeSelectorButtonGroup.Tag = 'GazeSelection';
            app.RightEyeSelectionButton = uiradiobutton(app.EyeSelectorButtonGroup);
            app.RightEyeSelectionButton.Tag = 'RightEyeMovementSelection';
            app.RightEyeSelectionButton.Text = 'Right';
            app.LeftEyeSelectionButton = uiradiobutton(app.EyeSelectorButtonGroup);
            app.LeftEyeSelectionButton.Tag = 'LeftEyeMovementSelection';
            app.LeftEyeSelectionButton.Text = 'Left';
            app.BinocularEyeSelectionButton = uiradiobutton(app.EyeSelectorButtonGroup);
            app.BinocularEyeSelectionButton.Tag = 'BinocularEyeMovementSelection';
            app.BinocularEyeSelectionButton.Text = 'Binocular';
            app.BinocularEyeSelectionButton.Value = true;
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PositionSelectionsPanel = uipanel(app.UIFigure);
            app.PositionSelectionsPanel.Title = 'Position Selections';
            app.RefractionPanel = uipanel(app.PositionSelectionsPanel);
            app.RefractionPanel.Title = 'Refraction';
            app.LeftRefractionCheckBox = uicheckbox(app.RefractionPanel);
            app.LeftRefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftRefractionCheckBoxValueChanged, true);
            app.LeftRefractionCheckBox.Text = 'Left';
            app.RightRefractionCheckBox = uicheckbox(app.RefractionPanel);
            app.RightRefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightRefractionCheckBoxValueChanged, true);
            app.RightRefractionCheckBox.Text = 'Right';
            app.AverageRefractionCheckBox = uicheckbox(app.RefractionPanel);
            app.AverageRefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageRefractionCheckBoxValueChanged, true);
            app.AverageRefractionCheckBox.Text = 'Average';
            app.LeftRSICheckBox = uicheckbox(app.RefractionPanel);
            app.LeftRSICheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftRSICheckBoxValueChanged, true);
            app.LeftRSICheckBox.Text = 'RSI';
            app.RightRSICheckBox = uicheckbox(app.RefractionPanel);
            app.RightRSICheckBox.ValueChangedFcn = createCallbackFcn(app, @RightRSICheckBoxValueChanged, true);
            app.RightRSICheckBox.Text = 'RSI';
            app.LeftREICheckBox = uicheckbox(app.RefractionPanel);
            app.LeftREICheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftREICheckBoxValueChanged, true);
            app.LeftREICheckBox.Text = 'REI';
            app.RightREICheckBox = uicheckbox(app.RefractionPanel);
            app.RightREICheckBox.ValueChangedFcn = createCallbackFcn(app, @RightREICheckBoxValueChanged, true);
            app.RightREICheckBox.Text = 'REI';
            app.AverageRSICheckBox = uicheckbox(app.RefractionPanel);
            app.AverageRSICheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageRSICheckBoxValueChanged, true);
            app.AverageRSICheckBox.Text = 'RSI';
            app.AverageREICheckBox = uicheckbox(app.RefractionPanel);
            app.AverageREICheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageREICheckBoxValueChanged, true);
            app.AverageREICheckBox.Text = 'REI';
            app.GazePanel = uipanel(app.PositionSelectionsPanel);
            app.GazePanel.Title = 'Gaze';
            app.LeftGazeCheckBox = uicheckbox(app.GazePanel);
            app.LeftGazeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftGazeCheckBoxValueChanged, true);
            app.LeftGazeCheckBox.Text = 'Left';
            app.RightGazeCheckBox = uicheckbox(app.GazePanel);
            app.RightGazeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightGazeCheckBoxValueChanged, true);
            app.RightGazeCheckBox.Text = 'Right';
            app.CombinedGazeCheckBox = uicheckbox(app.GazePanel);
            app.CombinedGazeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CombinedGazeCheckBoxValueChanged, true);
            app.CombinedGazeCheckBox.Text = 'Combined';
            app.LeftGazeRSICheckBox = uicheckbox(app.GazePanel);
            app.LeftGazeRSICheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftGazeRSICheckBoxValueChanged, true);
            app.LeftGazeRSICheckBox.Text = 'RSI';
            app.RightGazeRSICheckBox = uicheckbox(app.GazePanel);
            app.RightGazeRSICheckBox.ValueChangedFcn = createCallbackFcn(app, @RightGazeRSICheckBoxValueChanged, true);
            app.RightGazeRSICheckBox.Text = 'RSI';
            app.CombinedGazeRSICheckBox = uicheckbox(app.GazePanel);
            app.CombinedGazeRSICheckBox.ValueChangedFcn = createCallbackFcn(app, @CombinedGazeRSICheckBoxValueChanged, true);
            app.CombinedGazeRSICheckBox.Text = 'RSI';
            app.LeftGazeREICheckBox = uicheckbox(app.GazePanel);
            app.LeftGazeREICheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftGazeREICheckBoxValueChanged, true);
            app.LeftGazeREICheckBox.Text = 'REI';
            app.RightGazeREICheckBox = uicheckbox(app.GazePanel);
            app.RightGazeREICheckBox.ValueChangedFcn = createCallbackFcn(app, @RightGazeREICheckBoxValueChanged, true);
            app.RightGazeREICheckBox.Text = 'REI';
            app.CombinedGazeREICheckBox = uicheckbox(app.GazePanel);
            app.CombinedGazeREICheckBox.ValueChangedFcn = createCallbackFcn(app, @CombinedGazeREICheckBoxValueChanged, true);
            app.CombinedGazeREICheckBox.Text = 'REI';
            app.VelocitySelectionsPanel = uipanel(app.UIFigure);
            app.VelocitySelectionsPanel.Title = 'Velocity Selections';
            app.VelocitySelectionsPanel.Tag = 'GazeSelection';
            app.RefractionVelocityPanel = uipanel(app.VelocitySelectionsPanel);
            app.RefractionVelocityPanel.Title = 'Refraction ';
            app.LeftVelocityCheckBox = uicheckbox(app.RefractionVelocityPanel);
            app.LeftVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftVelocityCheckBoxValueChanged, true);
            app.LeftVelocityCheckBox.Text = 'Left';
            app.RightVelocityCheckBox = uicheckbox(app.RefractionVelocityPanel);
            app.RightVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightVelocityCheckBoxValueChanged, true);
            app.RightVelocityCheckBox.Text = 'Right';
            app.AverageVelocityCheckBox = uicheckbox(app.RefractionVelocityPanel);
            app.AverageVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageVelocityCheckBoxValueChanged, true);
            app.AverageVelocityCheckBox.Text = 'Average';
            app.LeftPVICheckBox = uicheckbox(app.RefractionVelocityPanel);
            app.LeftPVICheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftPVICheckBoxValueChanged, true);
            app.LeftPVICheckBox.Text = 'PVI';
            app.RightPVICheckBox = uicheckbox(app.RefractionVelocityPanel);
            app.RightPVICheckBox.ValueChangedFcn = createCallbackFcn(app, @RightPVICheckBoxValueChanged, true);
            app.RightPVICheckBox.Text = 'PVI';
            app.AveragePVICheckBox = uicheckbox(app.RefractionVelocityPanel);
            app.AveragePVICheckBox.ValueChangedFcn = createCallbackFcn(app, @AveragePVICheckBoxValueChanged, true);
            app.AveragePVICheckBox.Text = 'PVI';
            app.GazeVelocityPanel = uipanel(app.VelocitySelectionsPanel);
            app.GazeVelocityPanel.Title = 'Gaze';
            app.LeftGazeVelocityCheckBox = uicheckbox(app.GazeVelocityPanel);
            app.LeftGazeVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftGazeVelocityCheckBoxValueChanged, true);
            app.LeftGazeVelocityCheckBox.Text = 'Left';
            app.RightGazeVelocityCheckBox = uicheckbox(app.GazeVelocityPanel);
            app.RightGazeVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightGazeVelocityCheckBoxValueChanged, true);
            app.RightGazeVelocityCheckBox.Text = 'Right';
            app.CombinedGazeVelocityCheckBox = uicheckbox(app.GazeVelocityPanel);
            app.CombinedGazeVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CombinedGazeVelocityCheckBoxValueChanged, true);
            app.CombinedGazeVelocityCheckBox.Text = 'Combined';
            app.LeftGazePVICheckBox = uicheckbox(app.GazeVelocityPanel);
            app.LeftGazePVICheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftGazePVICheckBoxValueChanged, true);
            app.LeftGazePVICheckBox.Text = 'PVI';
            app.RightGazePVICheckBox = uicheckbox(app.GazeVelocityPanel);
            app.RightGazePVICheckBox.ValueChangedFcn = createCallbackFcn(app, @RightGazePVICheckBoxValueChanged, true);
            app.RightGazePVICheckBox.Text = 'PVI';
            app.CombinedGazePVICheckBox = uicheckbox(app.GazeVelocityPanel);
            app.CombinedGazePVICheckBox.ValueChangedFcn = createCallbackFcn(app, @CombinedGazePVICheckBoxValueChanged, true);
            app.CombinedGazePVICheckBox.Text = 'PVI';
            app.AccelerationSelectionsPanel = uipanel(app.UIFigure);
            app.AccelerationSelectionsPanel.Title = 'Acceleration Selections';
            app.AccelerationSelectionsPanel.Tag = 'GazeSelection';
            app.RefractionAccelerationPanel = uipanel(app.AccelerationSelectionsPanel);
            app.RefractionAccelerationPanel.Title = 'Refraction';
            app.LeftAccelerationCheckBox = uicheckbox(app.RefractionAccelerationPanel);
            app.LeftAccelerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @LeftAccelerationCheckBoxValueChanged, true);
            app.LeftAccelerationCheckBox.Text = 'Left';
            app.RightAccelerationCheckBox = uicheckbox(app.RefractionAccelerationPanel);
            app.RightAccelerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightAccelerationCheckBoxValueChanged, true);
            app.RightAccelerationCheckBox.Text = 'Right';
            app.AverageAccelerationCheckBox = uicheckbox(app.RefractionAccelerationPanel);
            app.AverageAccelerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageAccelerationCheckBoxValueChanged, true);
            app.AverageAccelerationCheckBox.Text = 'Average';
            app.TypeSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeSelectorButtonGroupSelectionChanged, true);
            app.TypeSelectorButtonGroup.Title = 'Type Selector';
            app.TypeSelectorButtonGroup.Tag = 'GazeSelection';
            app.RefractionSelectionButton = uiradiobutton(app.TypeSelectorButtonGroup);
            app.RefractionSelectionButton.Tag = 'RefractionSelection';
            app.RefractionSelectionButton.Text = 'Refraction';
            app.RefractionSelectionButton.Value = true;
            app.GazeSelectionButton = uiradiobutton(app.TypeSelectorButtonGroup);
            app.GazeSelectionButton.Tag = 'GazeSelection';
            app.GazeSelectionButton.Text = 'Gaze';
            app.MovementClassificationPanel = uipanel(app.UIFigure);
            app.MovementClassificationPanel.Title = 'Movement Classification';
            app.MovementClassificationPanel.Tag = 'GazeSelection';
            app.GoodClassificationButton = uibutton(app.MovementClassificationPanel, 'state');
            app.GoodClassificationButton.ValueChangedFcn = createCallbackFcn(app, @GoodClassificationButtonValueChanged, true);
            app.GoodClassificationButton.Text = 'Good';
            app.BadClassificationButton = uibutton(app.MovementClassificationPanel, 'state');
            app.BadClassificationButton.ValueChangedFcn = createCallbackFcn(app, @BadClassificationButtonValueChanged, true);
            app.BadClassificationButton.Text = 'Bad';
            app.MetricModificationsPanel = uipanel(app.UIFigure);
            app.MetricModificationsPanel.Title = 'Metric Modifications';
            app.RAEndIndexSpinner = uispinner(app.MetricModificationsPanel);
            app.RAEndIndexSpinner.Step = 0.02;
            app.RAEndIndexSpinner.Limits = [-0.02 2.98];
            app.RAEndIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @RAEndIndexSpinnerValueChanged, true);
            app.RAEndIndexSpinner.Tag = 'ScrollableREI';
            app.RAEndIndexSpinner.Editable = 'off';
            app.RAStartIndexSpinner = uispinner(app.MetricModificationsPanel);
            app.RAStartIndexSpinner.Step = 0.02;
            app.RAStartIndexSpinner.Limits = [-0.02 2.98];
            app.RAStartIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @RAStartIndexSpinnerValueChanged, true);
            app.RAStartIndexSpinner.Tag = 'ScrollableRSI';
            app.RAStartIndexSpinner.Editable = 'off';
            app.EndIndexSpinnerLabel = uilabel(app.MetricModificationsPanel);
            app.EndIndexSpinnerLabel.Text = 'REI';
            app.StartIndexSpinnerLabel = uilabel(app.MetricModificationsPanel);
            app.StartIndexSpinnerLabel.Text = 'RSI';
            app.PVIndexSpinner = uispinner(app.MetricModificationsPanel);
            app.PVIndexSpinner.Step = 0.02;
            app.PVIndexSpinner.Limits = [-0.02 2.98];
            app.PVIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @PVIndexSpinnerValueChanged, true);
            app.PVIndexSpinner.Tag = 'ScrollablePVI';
            app.PVIndexSpinner.Editable = 'off';
            app.PVISpinnerLabel = uilabel(app.MetricModificationsPanel);
            app.PVISpinnerLabel.Text = 'PVI';
            app.RSIUClickButton = uibutton(app.MetricModificationsPanel, 'push');
            app.RSIUClickButton.ButtonPushedFcn = createCallbackFcn(app, @RSIUClickButtonPushed, true);
            app.RSIUClickButton.Text = 'RSI UClick';
            app.PVIUClickButton = uibutton(app.MetricModificationsPanel, 'push');
            app.PVIUClickButton.ButtonPushedFcn = createCallbackFcn(app, @PVIUClickButtonPushed, true);
            app.PVIUClickButton.Text = 'PVI UClick';
            app.REIUClickButton = uibutton(app.MetricModificationsPanel, 'push');
            app.REIUClickButton.ButtonPushedFcn = createCallbackFcn(app, @REIUClickButtonPushed, true);
            app.REIUClickButton.Text = 'REI UClick';
            app.MetricSearchWindow = uispinner(app.MetricModificationsPanel);
            app.MetricSearchWindow.Limits = [1 10];
            app.MetricSearchWindow.ValueChangedFcn = createCallbackFcn(app, @MetricSearchWindowValueChanged, true);
            app.MetricSearchWindow.Tag = 'ScrollableREI';
            app.MetricSearchWindow.Editable = 'off';
            app.MetricSearchWindow.Value = 4;
            app.MetricSearchWindowLabel = uilabel(app.MetricModificationsPanel);
            app.MetricSearchWindowLabel.Text = 'Metric Window';
            app.MovementDropDownLabel = uilabel(app.UIFigure);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.UIFigure);
            app.MovementDropDown.Items = {};
            app.MovementDropDown.ValueChangedFcn = createCallbackFcn(app, @MovementDropDownValueChanged, true);
            app.MovementDropDown.Placeholder = 'ID #';
            app.MovementDropDown.Value = {};
            app.CurrentFileDropDownLabel = uilabel(app.UIFigure);
            app.CurrentFileDropDownLabel.Text = 'Current File';
            app.CurrentFileDropDown = uidropdown(app.UIFigure);
            app.CurrentFileDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentFileDropDownValueChanged, true);
            app.LoadDataLabel = uilabel(app.UIFigure);
            app.LoadDataLabel.Text = 'Load Data';
            app.MovementTypeLabel_2 = uilabel(app.UIFigure);
            app.MovementTypeLabel_2.Text = 'Movement Type:';
            app.RecoveredData = uicheckbox(app.UIFigure);
            app.RecoveredData.Text = 'Recovered Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMAP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end